classdef ManageAccount < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetPasswordButton    matlab.ui.control.Button
        DeleteButton           matlab.ui.control.Button
        CreateAccountPanel     matlab.ui.container.Panel
        PleaseentertheIDLabel  matlab.ui.control.Label
        TierDropDown_2         matlab.ui.control.DropDown
        PasswordEditField_2    matlab.ui.control.EditField
        IDEditField_2          matlab.ui.control.EditField
        CreateAccountButton    matlab.ui.control.Button
        InformationPanel       matlab.ui.container.Panel
        TierDropDown           matlab.ui.control.DropDown
        TierDropDownLabel      matlab.ui.control.Label
        IDEditField            matlab.ui.control.EditField
        IDEditFieldLabel       matlab.ui.control.Label
        ApplyChangesButton     matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
    end

    
    properties (Access = private)
        accounts;
        MIN_LENGTH_ID = 4;
        MAX_LENGTH_ID = 16;

    end
    
    methods (Access = private)
        
        function RenewAccountList(app)  
            if ~isfile('accounts.mat')                
                disp('Account information is missing or corrupted. Contact the manufacturer.')
                return;
            end

            loadedVar = load('accounts.mat');
            app.accounts = loadedVar.Accounts;
            ids = fieldnames(app.accounts);
            app.ListBox.Items = ids;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            RenewAccountList(app);
            ListBoxValueChanged(app, []);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.IDEditField.Value = app.ListBox.Value;
            app.TierDropDown.Value = app.accounts.(app.ListBox.Value).Tier;
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            % 최소 한명의 admin은 있어야 합니다.
        end

        % Button pushed function: ApplyChangesButton
        function ApplyChangesButtonPushed(app, event)
            if isempty(app.IDEditField.Value); disp('Please enter the ID'); return; end
            
            idNo = double(app.IDEditField.Value);
            % TODO : Refactoring to ValidId function
            if any(idNo < double('0')) ||...
                    any(idNo > double('9') & idNo < double('A')) ||...
                    any(idNo > double('Z') & idNo < double('a')) ||...
                    any(idNo > double('z'))
                disp('ID could only consist of letters and numbers');
                return;
            end

            if length(app.IDEditField.Value) < app.MIN_LENGTH_ID ||...
                    length(app.IDEditField.Value) > app.MAX_LENGTH_ID
                disp('ID could be between 4 and 16 characters in length');
                return;
            end
            disp('Good!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.InformationPanel = uipanel(app.UIFigure);
            app.InformationPanel.Title = 'Information';
            app.ApplyChangesButton = uibutton(app.InformationPanel, 'push');
            app.ApplyChangesButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyChangesButtonPushed, true);
            app.ApplyChangesButton.Text = 'Apply Changes';
            app.IDEditFieldLabel = uilabel(app.InformationPanel);
            app.IDEditFieldLabel.Text = 'ID :';
            app.IDEditField = uieditfield(app.InformationPanel, 'text');
            app.TierDropDownLabel = uilabel(app.InformationPanel);
            app.TierDropDownLabel.Text = 'Tier :';
            app.TierDropDown = uidropdown(app.InformationPanel);
            app.TierDropDown.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown.Value = 'Admin';
            app.CreateAccountPanel = uipanel(app.UIFigure);
            app.CreateAccountPanel.Title = 'Create Account';
            app.CreateAccountButton = uibutton(app.CreateAccountPanel, 'push');
            app.CreateAccountButton.Text = 'Create Account';
            app.IDEditField_2 = uieditfield(app.CreateAccountPanel, 'text');
            app.IDEditField_2.Placeholder = 'ID';
            app.PasswordEditField_2 = uieditfield(app.CreateAccountPanel, 'text');
            app.PasswordEditField_2.Placeholder = 'Password';
            app.TierDropDown_2 = uidropdown(app.CreateAccountPanel);
            app.TierDropDown_2.Items = {'Admin', 'Manager', 'Senior', 'Staff'};
            app.TierDropDown_2.Placeholder = 'Select a Tier';
            app.TierDropDown_2.Value = 'Staff';
            app.PleaseentertheIDLabel = uilabel(app.CreateAccountPanel);
            app.PleaseentertheIDLabel.Text = 'Please enter the ID';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.ResetPasswordButton = uibutton(app.UIFigure, 'push');
            app.ResetPasswordButton.Text = 'Reset Password';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ManageAccount

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end